classdef projet < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1      matlab.ui.Figure
        Help         matlab.ui.container.Menu
        uitable3     matlab.ui.control.Table
        text19       matlab.ui.control.Label
        text17       matlab.ui.control.Label
        text13       matlab.ui.control.Label
        pushbutton3  matlab.ui.control.Button
        text5        matlab.ui.control.Label
        text4        matlab.ui.control.Label
        text3        matlab.ui.control.Label
        uitable2     matlab.ui.control.Table
        text2        matlab.ui.control.Label
        uitable1     matlab.ui.control.Table
        pushbutton2  matlab.ui.control.Button
        listbox1     matlab.ui.control.Label
        pushbutton1  matlab.ui.control.Button
        axes1        matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)
            
            % Set component properties that require runtime configuration
            app.uitable1.BackgroundColor = [0.8039 0.8784 0.9686];
            app.uitable1.ColumnFormat = {[] [] [] [] [] [] [] [] [] []};
            app.uitable2.BackgroundColor = [0.8039 0.8784 0.9686];
            app.uitable2.ColumnFormat = {'numeric' 'numeric'};
            app.uitable3.BackgroundColor = [0.9294 0.6941 0.1255];
            app.uitable3.ColumnFormat = {'numeric' 'numeric' 'numeric' 'numeric' 'numeric'};
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function projet_OpeningFcn(app, varargin)
            % Add runtime required configuration - Added by Migration Tool
            addRuntimeConfigurations(app);
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to projet (see VARARGIN)
            
            % Choose default command line output for projet
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Menu selected function: Help
        function Help_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Help (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            open Help.pdf
        end

        % Button pushed function: pushbutton1
        function pushbutton1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global filename pathname;
            [filename pathname] = uigetfile('*.xlsx', 'Choose FILE to load:','MultiSelect','off');
            guidata(hObject, handles);
            set(handles.listbox1, 'string', filename);
            file = strcat(pathname,filename);
            setappdata(0,'pushbutton1',file);
            EXPERT = xlsread(getappdata(0,'pushbutton1'));
            set(handles.uitable3,'Data',num2cell(EXPERT));
        end

        % Button pushed function: pushbutton2
        function pushbutton2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            %PCM = getappdata(0,'pushbutton1');
            PCM = xlsread(getappdata(0,'pushbutton1'));
            PCM;
            
            %% Step 2: Normalized Matrix
            
            [V,D]=eig(PCM);
            X=V(:,1);
            somme=sum(X);
            jo=X./somme;
            
            [n, m] = size(PCM);
            Vpmax = max(eig(PCM));
            Vpmax;
            CI = (Vpmax-n)/(n-1);
            CR = CI/1.12;
            CR;
            
            CRb=1;
            Y=0;
            %% Step 3: Calculate Eij = aij* wj/wi
            while CRb > 0.1
              for i = 1:n
                for j = 1:m
                     C(i,j) = (PCM(i,j)*jo(j)/jo(i));
                     C(j,i)= 1/C(i,j);
                end
              end
            C;
            %% Step 4: Checking high matrice value
            
            M = max(C, [], 'all');
            N=n-1;
            for h =1:N
              s(1)=max(C, [], 'all');
                s(h+1)=max(C(C<s(h)));
                if Y==PCM(C==s(h))
                    M=s(h+1);
                end
            end
            M;
            
            %% Step 5: Replace max value BY 0 and two corresponding diagonal entries by 2
            
            [x,y]=find(C==M);
            [n, m] = size(C);
            
            PCM(C==M)=0;
            T = PCM; %%Make another array to fill up
            for i = 1:n
                for j = 1:m
                    if T(i,j) == 0
                        T(j,i) = 0;
                        T(i,i) = 2;
                        T(j,j) = 2;
                    end
                end
            end
            T;
            %% Step 6: two corresponding diagonal entries by 2
            
            [V1,D1]=eig(T);
            X1=V1(:,1);
            somme1=sum(X1);
            joA=X1./somme1;
            Scale=[1, 2, 3, 4, 5, 6, 7, 8, 9, 1/9, 1/8, 1/7, 1/6, 1/5, 1/4, 1/3, 1/2];
            for i = 1:n
                for j = 1:m
                    if PCM(i,j) == 0
                        Ag = joA(i)/joA(j);  % calculating wi/wj
                    end
                end
            end
            Ag;
            Z=[];
            for k=1:17
                Z(k)= abs(Ag - Scale(k));
            end
            mi=min(Z);
            l=find(Z==mi);
            Y=Scale(l);
            Z;
            
            for i = 1:n
                for j = 1:m
                    if PCM(i,j) == 0
                        PCM(i,j) = Y;
                        PCM(j,i) = 1/Y;
                    end
                end
            end
            PCM;
            set(handles.uitable1,'Data',PCM);
            
            %% Step 7: Recompute Normalized Matrix/eigen value
            
            [V2,D2]=eig(PCM);
            X2=V2(:,1);
            somme2=sum(X2);
            joB=X2./somme2;
            [n, m] = size(PCM);    % calculating CR
            VpmaxB = max(eig(PCM));
            VpmaxB;
            CIb = (VpmaxB-n)/(n-1);
            
            CRb = CIb/1.12;
            jo=joB;
            
            CRb
            set(handles.text3,'String',CRb)
            PCM;
            end
            Ag
            
            Vpmax
            
            [V,D] = eig(PCM) %produces a diagonal matrix D of eigenvalues and a full matrix V whose columns are the corresponding eigenvectors
            W = V(:,1)% In this case
            Wn = W./sum(W)
            
            %%%% Weight ranking %%%%
            [R,TIEADJ] = tiedrank(-Wn)
            T = [Wn R]
            set(handles.uitable2,'Data',T);
            
            
            %%% Plot bar %%%%
            
            x = 1:n; % arbitrary array
            y = Wn*[100];
            
            % Create a vector of '%' signs
            
               pct = char(ones(size(y,1),1)*'%');
            
            % Append the '%' signs after the percentage values
            
               new_yticks = [char(y),pct];
            
            % 'Reflect the changes on the plot
            
               set(gca,'yticklabel',new_yticks)
            
            bar(x,y);
            
            ylim([0 100])
            
            xlabel('Criteria');
            
            ylabel('Percentage');
            
            labels = arrayfun(@(value) num2str(value,'%2.2f'),y,'UniformOutput',false);
            z = '%';
            txt = strcat(labels,z);%concatener y avec %
            text(x,y,txt,'HorizontalAlignment','center','VerticalAlignment','bottom')
            
            %%%% Export PCM result and bar chart in Output %%%%
            global filename filepath;
            expertname = filename;
            [name, ~] = strsplit(expertname, '.');
            expertname = name{1};
            filepath = 'Output\';
            ax = gca;
            exportgraphics(ax,[filepath, expertname, '_BarChart.pdf'],'ContentType','vector');
            
            box off            
            result = [filepath, expertname, '_PCM.xlsx'];
            writematrix(PCM,result,'Sheet',1,'Range','A1:AC13')
        end

        % Button pushed function: pushbutton3
        function pushbutton3_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            filepath = ['Output\'];
            % Open the Output directory
            winopen(filepath);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'AHP';
            app.figure1.Tag = 'figure1';
            app.Help = uimenu(app.figure1);
            app.Help.MenuSelectedFcn = createCallbackFcn(app, @Help_Callback, true);
            app.Help.Text = 'Help';
            app.Help.Tag = 'Help';
            app.axes1 = uiaxes(app.figure1);
            app.axes1.XLim = [0 10];
            app.axes1.YLim = [0 100];
            app.axes1.Tag = 'axes1';
            app.pushbutton1 = uibutton(app.figure1, 'push');
            app.pushbutton1.ButtonPushedFcn = createCallbackFcn(app, @pushbutton1_Callback, true);
            app.pushbutton1.Tag = 'pushbutton1';
            app.pushbutton1.Text = 'Load .xlsx ';
            app.listbox1 = uilabel(app.figure1);
            app.listbox1.Tag = 'listbox1';
            app.listbox1.Text = '';
            app.pushbutton2 = uibutton(app.figure1, 'push');
            app.pushbutton2.ButtonPushedFcn = createCallbackFcn(app, @pushbutton2_Callback, true);
            app.pushbutton2.Tag = 'pushbutton2';
            app.pushbutton2.Text = 'Calculate';
            app.uitable1 = uitable(app.figure1);
            app.uitable1.ColumnName = '';
            app.uitable1.RowName = '';
            app.uitable1.ColumnEditable = [false false false false false false false false false false];
            app.uitable1.Tag = 'uitable1';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Computed Consistency Ratio (CR) :';
            app.uitable2 = uitable(app.figure1);
            app.uitable2.ColumnName = {'Weight'; 'Rank'};
            app.uitable2.RowName = '';
            app.uitable2.ColumnEditable = [false false];
            app.uitable2.Tag = 'uitable2';
            app.text3 = uilabel(app.figure1);
            app.text3.Tag = 'text3';
            app.text3.Text = '';
            app.text4 = uilabel(app.figure1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Pair wise matrix output';
            app.text5 = uilabel(app.figure1);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Criteria weight';
            app.pushbutton3 = uibutton(app.figure1, 'push');
            app.pushbutton3.ButtonPushedFcn = createCallbackFcn(app, @pushbutton3_Callback, true);
            app.pushbutton3.Tag = 'pushbutton3';
            app.pushbutton3.Text = 'Open Output directory';
            app.text13 = uilabel(app.figure1);
            app.text13.Tag = 'text13';
            app.text13.Text = 'Weight priority histogram';
            app.text17 = uilabel(app.figure1);
            app.text17.Tag = 'text17';
            app.text17.Text = 'Inputs';
            app.text19 = uilabel(app.figure1);
            app.text19.Tag = 'text19';
            app.text19.Text = 'Outputs';
            app.uitable3 = uitable(app.figure1);
            app.uitable3.ColumnName = '';
            app.uitable3.RowName = '';
            app.uitable3.ColumnEditable = [false false false false false];
            app.uitable3.Tag = 'uitable3';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = projet(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)projet_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end